classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RootfindingLabel              matlab.ui.control.Label
        FXEditFieldLabel              matlab.ui.control.Label
        FXEditField                   matlab.ui.control.EditField
        InputsPanel                   matlab.ui.container.Panel
        numberofiterationsLabel       matlab.ui.control.Label
        numberofiterationsEditField   matlab.ui.control.NumericEditField
        x_lowerEditFieldLabel         matlab.ui.control.Label
        x_lowerEditField              matlab.ui.control.NumericEditField
        x_upperEditFieldLabel         matlab.ui.control.Label
        x_upperEditField              matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel       matlab.ui.control.Label
        ToleranceEditField            matlab.ui.control.NumericEditField
        SOLVEButton                   matlab.ui.control.Button
        loadfromfileButton            matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        OutputsPanel                  matlab.ui.container.Panel
        RootEditFieldLabel            matlab.ui.control.Label
        RootEditField                 matlab.ui.control.NumericEditField
        ElapsedtimeEditFieldLabel     matlab.ui.control.Label
        ElapsedtimeEditField          matlab.ui.control.NumericEditField
        noofiterationsEditFieldLabel  matlab.ui.control.Label
        noofiterationsEditField       matlab.ui.control.NumericEditField
        ChoosemethodDropDownLabel     matlab.ui.control.Label
        ChoosemethodDropDown          matlab.ui.control.DropDown
        ddxEditFieldLabel             matlab.ui.control.Label
        ddxEditField                  matlab.ui.control.EditField
    end

    
    properties (Access = public)
        Fun % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SOLVEButton
        function SOLVEButtonPushed(app, event)


            try
            f=inline(app.FXEditField.Value); 
            m=f(app.x_lowerEditField.Value);
            catch ME
                f = msgbox('error invalid equation');
            end
            app.UITable.Data=[];
            x=zeros;
            
            
            %Bisection
            if(strcmp(app.ChoosemethodDropDown.Value,'Bisection'))
            app.UITable.ColumnFormat={'char','numeric','numeric','numeric','numeric','numeric'};    
            x1=app.x_lowerEditField.Value;
            x2=app.x_upperEditField.Value;
            tol=app.ToleranceEditField.Value;
            imax=app.numberofiterationsEditField.Value;
           eps=abs(x2-x1)/x2;
           i=1;
           xr = -1;
            ts = tic;
            if(f(x1)*f(x2)>0)
              f = msgbox('error no brackets');
            end
           while eps>tol&&i<=imax
               xr=(x2+x1)/2;
               app.UITable.Data(i,:)=[i,x1,x2,xr,f(xr),eps];
               if i==1
                   app.UITable.Data(1,6)=0;
               end
            if f(xr)*f(x2)<0
              x1=xr;
            elseif f(xr)*f(x1)<0
              x2=xr;
            else
            break;
            end
            eps=abs(x2-x1);
              i=i+1;          
            end
            app.noofiterationsEditField.Value=i-1;
             app.RootEditField.Value=xr;
             app.ElapsedtimeEditField.Value = toc(ts);


            %false position
            elseif(strcmp(app.ChoosemethodDropDown.Value,'False-Position'))
             app.UITable.ColumnFormat={'char','numeric','numeric','numeric','numeric','long'};    
            x1=app.x_lowerEditField.Value;
            x2=app.x_upperEditField.Value;
            tol=app.ToleranceEditField.Value;
            imax=app.numberofiterationsEditField.Value;
           
          if(f(x1)*f(x2)>0)
             f = msgbox('error no brackets');
          else
            xr=x1-(x1-x2)*f(x1)/(f(x1)-f(x2));
            i=1;
           app.UITable.Data(1,:)=[i,x1, x2 ,xr,f(xr), 0]; 
            ts = tic;
           while(abs(f(xr))>tol&&i<=imax)
           xold=xr;
           if(f(x1)*f(xr)<0)
              x2=xr; 
           else
              x1=xr;
           end
              xr=x1-(x1-x2)*f(x1)/(f(x1)-f(x2));
            AbsErr=abs(xr-xold)/xr;
             app.UITable.Data(i+1,:)=[i,x1, x2 ,xr,f(xr), AbsErr]; 
           i=i+1;
           end %while
           app.noofiterationsEditField.Value=i-1;
             app.RootEditField.Value=xr;
             app.ElapsedtimeEditField.Value = toc(ts);
          end %if


           %fixed point:
            elseif (strcmp(app.ChoosemethodDropDown.Value,'Fixed-Point'))
                app.UITable.ColumnFormat={'numeric','numeric','numeric'};
                X0 = app.x_lowerEditField.Value;
                tol = app.ToleranceEditField.Value;
                imax=app.numberofiterationsEditField.Value;
                Xi = X0;
                Xi1 = X0;
                i = 1;
                 
               
                ts = tic;
             try
                for i = 1:imax
                    Xi = Xi1;
                    Xi1 = f(Xi);
                    eps = abs((Xi1 - Xi)/Xi1);
                    app.UITable.Data(i,:)=[i,Xi,eps];
                    if eps < tol
                        break;
                    end
                end
                app.UITable.Data(1,3)=0;
                app.RootEditField.Value=Xi1;
                app.noofiterationsEditField.Value=i;
                app.ElapsedtimeEditField.Value = toc(ts);  
             catch
                f = msgbox('error method diverge');  
             end
                   
          
             %Secant
             elseif(strcmp(app.ChoosemethodDropDown.Value,'Secant'))
                app.UITable.ColumnFormat={'numeric','numeric','numeric','numeric','numeric', 'numeric', 'numeric'};
                X1 = app.x_lowerEditField.Value;
                X2 = app.x_upperEditField.Value;
                X3  = 0;
                i = 0;
                ts = tic;
              try
                for i=1:app.numberofiterationsEditField.Value
                    X3 = X2 - f(X2) * ((X2 - X1)/(f(X2) - f(X1)));
                    AbsErr =abs((X3-X2)/X3);
                   app.UITable.Data(i,:)=[i,X1, X2 ,f(X1), f(X2), X3 ,AbsErr];
                    if AbsErr <= app.ToleranceEditField.Value
                        break;
                    end
                    X1 = X2;
                    X2 = X3;
                end
                app.noofiterationsEditField.Value=i;
                app.RootEditField.Value=X3; 
                app.ElapsedtimeEditField.Value = toc(ts);
             catch
                f = msgbox('error method diverge');  
             end
            
            
            %Newton-Raphson
            elseif(strcmp(app.ChoosemethodDropDown.Value,'Newton-Raphson'))
                app.UITable.ColumnFormat={'char','numeric','numeric','numeric','numeric'};
                x=zeros;
                df=diff(str2sym(app.Fun));
                app.ddxEditField.Value=char(df);
                d=inline(df);
                x(1)=app.x_lowerEditField.Value;
                 ts = tic;
                for i=1:app.numberofiterationsEditField.Value
                   x(i+1)=x(i)-((f(x(i))/d(x(i))));
                   AbsErr(i)=abs((x(i+1)-x(i))/x(i+1));
                   app.UITable.Data(i,:)=[i,x(i), x(i+1) ,f(x(i+1)) ,AbsErr(i)];
                    if AbsErr(i)<=app.ToleranceEditField.Value
                        break;
                    end   
                end   %for 
                        app.noofiterationsEditField.Value=i;
                        app.RootEditField.Value=x(i); 
                        app.ElapsedtimeEditField.Value = toc(ts);
                x=zeros;
                AbsErr=zeros;
            end
            
        end

        % Value changed function: FXEditField
        function FXEditFieldValueChanged(app, event)
           app.Fun = app.FXEditField.Value;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            
        end

        % Value changed function: ChoosemethodDropDown
        function ChoosemethodDropDownValueChanged(app, event)
           %clear fields 
            app.numberofiterationsEditField.Value=50;
            app.x_lowerEditField.Value=0;
            app.x_upperEditField.Value=0;
            app.ToleranceEditField.Value=0.000001;
            app.RootEditField.Value=0;
            app.ElapsedtimeEditField.Value=0;
            app.noofiterationsEditField.Value=0;
            app.UITable.Data=[];
            value = app.ChoosemethodDropDown.Value;


            %Bisection
            if(strcmp(value,'Bisection'))
                %edit gui visible buttons
                
                app.x_upperEditField.Visible='on';
                app.x_upperEditFieldLabel.Visible='on';
                app.x_upperEditFieldLabel.Text='x_upper';
                app.x_lowerEditFieldLabel.Text='x_lower';
                app.UITable.ColumnName = {'iteration'; 'x_low'; 'x_upp';'x_r';'f(x_r)'; 'relative error'};
                app.ddxEditField.Visible='off';
                app.ddxEditFieldLabel.Visible='off'; 
                app.FXEditFieldLabel.Text='f(x)';


            %'False-Position'
            elseif (strcmp(value,'False-Position'))
                app.UITable.ColumnName = {'iteration'; 'x_low'; 'x_upp';'x_r';'f(x_r)'; 'relative error'};
                app.ddxEditField.Visible='off';
                app.ddxEditFieldLabel.Visible='off'; 
                app.x_upperEditField.Visible='on';
                app.x_upperEditFieldLabel.Visible='on';
                app.x_upperEditFieldLabel.Text='x_upper';
                app.x_lowerEditFieldLabel.Text='x_lower';
                app.FXEditFieldLabel.Text='f(x)';
                
                
            %fixed point method:
            elseif (strcmp(value, 'Fixed-Point'))
                app.x_upperEditField.Visible='off';
                app.x_upperEditFieldLabel.Visible='off';
                app.x_lowerEditFieldLabel.Text='X0';
                app.x_lowerEditFieldLabel.Visible = 'on';
                app.UITable.ColumnName = {'iteration'; 'X(i)'; 'E(a)'};
                app.ddxEditField.Visible='off';
                app.ddxEditFieldLabel.Visible='off'; 
                app.FXEditFieldLabel.Text='g(x)';
            
                
            %secant point method:
            elseif (strcmp(value, 'Secant'))
                app.x_upperEditField.Visible='on';
                app.x_upperEditFieldLabel.Visible='on';
                app.x_upperEditFieldLabel.Text='x(i)';
                app.x_lowerEditFieldLabel.Text='x(i-1)';
                app.UITable.ColumnName = {'iteration'; 'x(i-1)'; 'x(i)';'f(X(i-1))';'f(X(i))';'x(i + 1)'; 'relative error'};
                app.ddxEditField.Visible='off';
                app.ddxEditFieldLabel.Visible='off'; 
                app.FXEditFieldLabel.Text='f(x)';
            
            
            %Newton-Raphson
            elseif(strcmp(value,'Newton-Raphson'))
                app.x_upperEditField.Visible='off';
                app.x_upperEditFieldLabel.Visible='off';
                app.x_lowerEditFieldLabel.Text='inital index';
                app.UITable.ColumnName = {'iteration'; 'x_old'; 'x_new'; 'F(x_new)'; 'relative error'};
                app.ddxEditField.Visible='on';
                app.ddxEditFieldLabel.Visible='on'; 
                app.FXEditFieldLabel.Text='f(x)';


            end
        end

        % Button pushed function: loadfromfileButton
        function loadfromfileButtonPushed(app, event)
            if(strcmp(app.ChoosemethodDropDown.Value,'None'))
                return;
            end
            [filename,path]=uigetfile('*.txt');
            figure(app.UIFigure);
            file=fopen(filename)
            app.FXEditField.Value = fgetl(file);
            app.numberofiterationsEditField.Value = str2double(fgetl(file));
            app.ToleranceEditField.Value = str2double(fgetl(file));
            %T=readtable(filename);
            %ar=table2array(T);
            %bisection

           if(strcmp(app.ChoosemethodDropDown.Value,'Bisection'))
               app.x_lowerEditField.Value=str2double(fgetl(file));
               app.x_upperEditField.Value=str2double(fgetl(file));
           elseif(strcmp(app.ChoosemethodDropDown.Value,'False-Position'))
               app.x_lowerEditField.Value=str2double(fgetl(file));
               app.x_upperEditField.Value=str2double(fgetl(file));
           elseif(strcmp(app.ChoosemethodDropDown.Value,'Fixed-Point'))
               app.x_lowerEditField.Value=str2double(fgetl(file)); 
           elseif(strcmp(app.ChoosemethodDropDown.Value,'Newton-Raphson'))
               app.x_lowerEditField.Value=str2double(fgetl(file));
           elseif(strcmp(app.ChoosemethodDropDown.Value,'Secant'))
               app.x_lowerEditField.Value=str2double(fgetl(file));
               app.x_upperEditField.Value=str2double(fgetl(file));
           end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RootfindingLabel = uilabel(app.UIFigure);
            app.RootfindingLabel.Text = 'Root finding';
            app.FXEditFieldLabel = uilabel(app.UIFigure);
            app.FXEditFieldLabel.Text = 'F(X)';
            app.FXEditField = uieditfield(app.UIFigure, 'text');
            app.FXEditField.ValueChangedFcn = createCallbackFcn(app, @FXEditFieldValueChanged, true);
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.numberofiterationsLabel = uilabel(app.InputsPanel);
            app.numberofiterationsLabel.Text = 'number of iterations ';
            app.numberofiterationsEditField = uieditfield(app.InputsPanel, 'numeric');
            app.numberofiterationsEditField.Value = 50;
            app.x_lowerEditFieldLabel = uilabel(app.InputsPanel);
            app.x_lowerEditFieldLabel.Text = 'x_lower  ';
            app.x_lowerEditField = uieditfield(app.InputsPanel, 'numeric');
            app.x_upperEditFieldLabel = uilabel(app.InputsPanel);
            app.x_upperEditFieldLabel.Text = 'x_upper ';
            app.x_upperEditField = uieditfield(app.InputsPanel, 'numeric');
            app.ToleranceEditFieldLabel = uilabel(app.InputsPanel);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.InputsPanel, 'numeric');
            app.ToleranceEditField.Value = 1e-05;
            app.SOLVEButton = uibutton(app.InputsPanel, 'push');
            app.SOLVEButton.ButtonPushedFcn = createCallbackFcn(app, @SOLVEButtonPushed, true);
            app.SOLVEButton.Text = 'SOLVE';
            app.loadfromfileButton = uibutton(app.InputsPanel, 'push');
            app.loadfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadfromfileButtonPushed, true);
            app.loadfromfileButton.Text = 'load from file';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'iteration'; 'x_old'; 'x_new'; 'F(x_new)'; 'relative error'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.OutputsPanel = uipanel(app.UIFigure);
            app.OutputsPanel.Title = 'Outputs';
            app.RootEditFieldLabel = uilabel(app.OutputsPanel);
            app.RootEditFieldLabel.Text = 'Root';
            app.RootEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.RootEditField.Editable = 'off';
            app.ElapsedtimeEditFieldLabel = uilabel(app.OutputsPanel);
            app.ElapsedtimeEditFieldLabel.Text = 'Elapsed time';
            app.ElapsedtimeEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.ElapsedtimeEditField.Editable = 'off';
            app.noofiterationsEditFieldLabel = uilabel(app.OutputsPanel);
            app.noofiterationsEditFieldLabel.Text = 'no.of.iterations';
            app.noofiterationsEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.noofiterationsEditField.Editable = 'off';
            app.ChoosemethodDropDownLabel = uilabel(app.UIFigure);
            app.ChoosemethodDropDownLabel.Text = 'Choose method';
            app.ChoosemethodDropDown = uidropdown(app.UIFigure);
            app.ChoosemethodDropDown.Items = {'None', 'Bisection', 'False-Position', 'Fixed-Point', 'Newton-Raphson', 'Secant'};
            app.ChoosemethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosemethodDropDownValueChanged, true);
            app.ChoosemethodDropDown.Value = 'None';
            app.ddxEditFieldLabel = uilabel(app.UIFigure);
            app.ddxEditFieldLabel.Text = 'd/dx';
            app.ddxEditField = uieditfield(app.UIFigure, 'text');
            app.ddxEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end